classdef depression < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        StudentProfileTab              matlab.ui.container.Tab
        BreatheeLabel                  matlab.ui.control.Label
        BreatheeisaMATLABbasedmentalhealthscreeningtooldesignedtoassistintheearlyidentificationofanxietydepressionandoverallmentalhealthrisklevelsamonguniversitystudentsLabel  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        NextButton                     matlab.ui.control.Button
        StudentProfileAssessmentPanel  matlab.ui.container.Panel
        CGPAEditField                  matlab.ui.control.NumericEditField
        CGPAEditFieldLabel             matlab.ui.control.Label
        RiskscaleGauge                 matlab.ui.control.LinearGauge
        RiskscaleGaugeLabel            matlab.ui.control.Label
        Study_fieldDropDown            matlab.ui.control.DropDown
        Study_fieldDropDownLabel       matlab.ui.control.Label
        AgeEditField                   matlab.ui.control.NumericEditField
        AgeEditFieldLabel              matlab.ui.control.Label
        RiskEditField_2                matlab.ui.control.EditField
        RiskEditField_2Label           matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        AnxietyAssessmentTab           matlab.ui.container.Tab
        UnderstandingbeginswithawarenessandyourenotaloneonthisjourneyLabel  matlab.ui.control.Label
        BreatheeLabel_2                matlab.ui.control.Label
        Image2_2                       matlab.ui.control.Image
        NextButton_3                   matlab.ui.control.Button
        AnxietyAssessmentPanel         matlab.ui.container.Panel
        AnxietyscaleGauge              matlab.ui.control.LinearGauge
        AnxietyscaleGaugeLabel         matlab.ui.control.Label
        AnxietylevelEditField_2        matlab.ui.control.EditField
        AnxietylevelEditField_2Label   matlab.ui.control.Label
        Lamp_2                         matlab.ui.control.Lamp
        SweatingSlider                 matlab.ui.control.Slider
        SweatingSliderLabel            matlab.ui.control.Label
        NervousnessSlider              matlab.ui.control.Slider
        NervousnessSliderLabel         matlab.ui.control.Label
        PanicSlider                    matlab.ui.control.Slider
        PanicSliderLabel               matlab.ui.control.Label
        DepressionAssessmentTab        matlab.ui.container.Tab
        YourJourneyAlmostBeginLabel    matlab.ui.control.Label
        BreatheeLabel_3                matlab.ui.control.Label
        Image2_3                       matlab.ui.control.Image
        DepressionAssessmentPanel      matlab.ui.container.Panel
        DepressionScaleGauge           matlab.ui.control.LinearGauge
        DepressionScaleGaugeLabel      matlab.ui.control.Label
        DepressionLevelEditField       matlab.ui.control.EditField
        DepressionLevelEditFieldLabel  matlab.ui.control.Label
        Lamp_3                         matlab.ui.control.Lamp
        SuicidalthoughtSlider          matlab.ui.control.Slider
        SuicidalthoughtSliderLabel     matlab.ui.control.Label
        FatigueSlider                  matlab.ui.control.Slider
        FatigueSliderLabel             matlab.ui.control.Label
        FrustrationSlider              matlab.ui.control.Slider
        FrustrationSliderLabel         matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
    end

    
properties (Access = private)
    FIS  % Fuzzy Inference System
end
 
methods (Access = private)
%% --- Tab navigation helper
function goToTab(app, tabHandle)
    app.TabGroup.SelectedTab = tabHandle;
end


function updateRiskPanel(app, ~)
    % Map field of study to numeric code
    switch app.Study_fieldDropDown.Value
        case 'Medical',     studyField = 1;
        case 'STEM',        studyField = 2;
        case 'Soc.Sc&Arts', studyField = 3;
    end

    % Build 9‑input vector (first 3 real, rest zeros)
    in = [ ...
        app.AgeEditField.Value, ...
        app.CGPAEditField.Value, ...
        studyField, ...
        zeros(1,6) ...  % placeholders for the other FIS inputs
    ];

    % Evaluate FIS – we only care about output(1) = Risk
    out = evalfis(app.FIS, in);
    risk = out(1);

    % Update gauge & UI
    app.RiskscaleGauge.Value = risk;
    if risk < 0.33
        app.Lamp.Color        = 'green';
        app.RiskEditField_2.Value = "LOW";
    elseif risk < 0.66
        app.Lamp.Color        = 'yellow';
        app.RiskEditField_2.Value = "MODERATE";
    else
        app.Lamp.Color        = 'red';
        app.RiskEditField_2.Value = "HIGH";
    end
end


%% --- Live update for the Anxiety panel
function updateAnxietyPanel(app, ~)
    score = mean([app.PanicSlider.Value,...
                  app.NervousnessSlider.Value,...
                  app.SweatingSlider.Value]);

    app.AnxietyscaleGauge.Value = score;

    if score < 0.33
        app.Lamp_2.Color = 'green';
        app.AnxietylevelEditField_2.Value = "LOW";
    elseif score < 0.66
        app.Lamp_2.Color = 'yellow';
        app.AnxietylevelEditField_2.Value = "MODERATE";
    else
        app.Lamp_2.Color = 'red';
        app.AnxietylevelEditField_2.Value = "HIGH";
    end
end

function calculateScores(app, ~)
    % Map dropdown field to numeric value
    switch app.Study_fieldDropDown.Value
        case 'Medical',        studyField = 1;
        case 'STEM',           studyField = 2;
        case 'Soc.Sc&Arts',    studyField = 3;
    end

   % === Prepare 9‑input vector (must match FIS order) ===
inputs = [
    app.AgeEditField.Value       , ...  % 1  Age
    app.CGPAEditField.Value      , ...  % 2  CGPA
    studyField                   , ...  % 3  Study‑field code
    app.PanicSlider.Value        , ...  % 4  Panic      (Anxiety layer)
    app.NervousnessSlider.Value  , ...  % 5  Nervousness(Anxiety layer)
    app.SweatingSlider.Value     , ...  % 6  Sweating   (Anxiety layer)
    app.FatigueSlider.Value         ... % 7  Fatigue      (Depression layer)
    app.FrustrationSlider.Value  , ...  % 8  Frustration (Depression layer)
    app.SuicidalthoughtSlider.Value, ...% 9  Suicidal thought (Depression layer)
    
];


    % === Run FIS ===
    outputs = evalfis(app.FIS, inputs);  % Assuming output(1)=Risk, output(2)=AnxietyLevel, output(3)=DepressionLevel

    % === Show values to user ===
    % Output(1): Risk
    app.RiskscaleGauge.Value = outputs(1);
    if outputs(1) < 0.33
        app.Lamp.Color = 'green';
        app.RiskEditField_2.Value = "LOW";
    elseif outputs(1) < 0.66
        app.Lamp.Color = 'yellow';
        app.RiskEditField_2.Value = "MODERATE";
    else
        app.Lamp.Color = 'red';
        app.RiskEditField_2.Value = "HIGH";
    end

    % Output(2): Anxiety
    app.AnxietyscaleGauge.Value = outputs(2);
    if outputs(2) < 0.33
        app.Lamp_2.Color = 'green';
        app.AnxietylevelEditField_2.Value = "LOW";
    elseif outputs(2) < 0.66
        app.Lamp_2.Color = 'yellow';
        app.AnxietylevelEditField_2.Value = "MEDIUM";
    else
        app.Lamp_2.Color = 'red';
        app.AnxietylevelEditField_2.Value = "HIGH";
    end

    % Output(3): Depression
    app.DepressionScaleGauge.Value = outputs(3);
    if outputs(3) < 0.33
        app.Lamp_3.Color = 'green';
        app.DepressionLevelEditField.Value = "Healthy / Normal";
    elseif outputs(3) < 0.66
        app.Lamp_3.Color = 'yellow';
        app.DepressionLevelEditField.Value = "Mild Risk";
    else
        app.Lamp_3.Color = 'red';
        app.DepressionLevelEditField.Value = "High Risk – seek help";
    end

    uialert(app.UIFigure, "Assessment complete!", "Done");
end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    % Load combined multi-layer FIS system
    s = load('DepressionFIS.mat');
    app.FIS = s.FISForPredictionOfAnxiety_DepressionAmongStudents;

    % Tab and button setup
    app.NextButton.ButtonPushedFcn = @(~,~)app.goToTab(app.AnxietyAssessmentTab);
    app.NextButton_3.ButtonPushedFcn = @(~,~)app.goToTab(app.DepressionAssessmentTab);
    app.CalculateButton.ButtonPushedFcn = @(~,~)app.calculateScores();

    % Slider update setup
    app.PanicSlider.ValueChangedFcn = @(~,~)app.updateAnxietyPanel();
    app.NervousnessSlider.ValueChangedFcn = @(~,~)app.updateAnxietyPanel();
    app.SweatingSlider.ValueChangedFcn = @(~,~)app.updateAnxietyPanel();

    % Live risk update when profile values change
    app.AgeEditField.ValueChangedFcn        = @(~,~)app.updateRiskPanel();
    app.CGPAEditField.ValueChangedFcn       = @(~,~)app.updateRiskPanel();
    app.Study_fieldDropDown.ValueChangedFcn = @(~,~)app.updateRiskPanel();

% Run once so the panel isn’t blank at launch
app.updateRiskPanel();


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StudentProfileTab = uitab(app.TabGroup);
            app.StudentProfileTab.Title = 'Student Profile';
            app.StudentProfileAssessmentPanel = uipanel(app.StudentProfileTab);
            app.StudentProfileAssessmentPanel.Title = 'Student Profile Assessment';
            app.Lamp = uilamp(app.StudentProfileAssessmentPanel);
            app.RiskEditField_2Label = uilabel(app.StudentProfileAssessmentPanel);
            app.RiskEditField_2Label.Text = 'Risk';
            app.RiskEditField_2 = uieditfield(app.StudentProfileAssessmentPanel, 'text');
            app.AgeEditFieldLabel = uilabel(app.StudentProfileAssessmentPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.StudentProfileAssessmentPanel, 'numeric');
            app.AgeEditField.Limits = [18 30];
            app.AgeEditField.ValueDisplayFormat = '%.0f';
            app.AgeEditField.Placeholder = '0';
            app.AgeEditField.Value = 18;
            app.Study_fieldDropDownLabel = uilabel(app.StudentProfileAssessmentPanel);
            app.Study_fieldDropDownLabel.Text = 'Study_field';
            app.Study_fieldDropDown = uidropdown(app.StudentProfileAssessmentPanel);
            app.Study_fieldDropDown.Items = {'Medical', 'STEM', 'Soc.Sc&Arts'};
            app.Study_fieldDropDown.Value = 'Medical';
            app.RiskscaleGaugeLabel = uilabel(app.StudentProfileAssessmentPanel);
            app.RiskscaleGaugeLabel.Text = 'Risk scale';
            app.RiskscaleGauge = uigauge(app.StudentProfileAssessmentPanel, 'linear');
            app.RiskscaleGauge.Limits = [0 1];
            app.CGPAEditFieldLabel = uilabel(app.StudentProfileAssessmentPanel);
            app.CGPAEditFieldLabel.Text = 'CGPA';
            app.CGPAEditField = uieditfield(app.StudentProfileAssessmentPanel, 'numeric');
            app.CGPAEditField.Limits = [1 4];
            app.CGPAEditField.ValueDisplayFormat = '%.2f';
            app.CGPAEditField.Placeholder = '0';
            app.CGPAEditField.Value = 4;
            app.NextButton = uibutton(app.StudentProfileTab, 'push');
            app.NextButton.Text = 'Next';
            app.Image2 = uiimage(app.StudentProfileTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlab1.jpg');
            app.BreatheeisaMATLABbasedmentalhealthscreeningtooldesignedtoassistintheearlyidentificationofanxietydepressionandoverallmentalhealthrisklevelsamonguniversitystudentsLabel = uilabel(app.StudentProfileTab);
            app.BreatheeisaMATLABbasedmentalhealthscreeningtooldesignedtoassistintheearlyidentificationofanxietydepressionandoverallmentalhealthrisklevelsamonguniversitystudentsLabel.Text = {'Breathee is a MATLAB-based mental health '; 'screening tool designed to assist in the '; 'early identification of anxiety, depression, and'; 'overall mental health risk levels'; 'among university students.'};
            app.BreatheeLabel = uilabel(app.StudentProfileTab);
            app.BreatheeLabel.Text = 'Breathee';
            app.AnxietyAssessmentTab = uitab(app.TabGroup);
            app.AnxietyAssessmentTab.Title = 'Anxiety Assessment';
            app.AnxietyAssessmentPanel = uipanel(app.AnxietyAssessmentTab);
            app.AnxietyAssessmentPanel.Title = 'Anxiety Assessment';
            app.PanicSliderLabel = uilabel(app.AnxietyAssessmentPanel);
            app.PanicSliderLabel.Text = 'Panic';
            app.PanicSlider = uislider(app.AnxietyAssessmentPanel);
            app.PanicSlider.Limits = [0 1];
            app.NervousnessSliderLabel = uilabel(app.AnxietyAssessmentPanel);
            app.NervousnessSliderLabel.Text = 'Nervousness';
            app.NervousnessSlider = uislider(app.AnxietyAssessmentPanel);
            app.NervousnessSlider.Limits = [0 1];
            app.SweatingSliderLabel = uilabel(app.AnxietyAssessmentPanel);
            app.SweatingSliderLabel.Text = 'Sweating';
            app.SweatingSlider = uislider(app.AnxietyAssessmentPanel);
            app.SweatingSlider.Limits = [0 1];
            app.Lamp_2 = uilamp(app.AnxietyAssessmentPanel);
            app.AnxietylevelEditField_2Label = uilabel(app.AnxietyAssessmentPanel);
            app.AnxietylevelEditField_2Label.Text = 'Anxiety level';
            app.AnxietylevelEditField_2 = uieditfield(app.AnxietyAssessmentPanel, 'text');
            app.AnxietyscaleGaugeLabel = uilabel(app.AnxietyAssessmentPanel);
            app.AnxietyscaleGaugeLabel.Text = 'Anxiety scale';
            app.AnxietyscaleGauge = uigauge(app.AnxietyAssessmentPanel, 'linear');
            app.AnxietyscaleGauge.Limits = [0 1];
            app.NextButton_3 = uibutton(app.AnxietyAssessmentTab, 'push');
            app.NextButton_3.Text = 'Next';
            app.Image2_2 = uiimage(app.AnxietyAssessmentTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'matlab1.jpg');
            app.BreatheeLabel_2 = uilabel(app.AnxietyAssessmentTab);
            app.BreatheeLabel_2.Text = 'Breathee';
            app.UnderstandingbeginswithawarenessandyourenotaloneonthisjourneyLabel = uilabel(app.AnxietyAssessmentTab);
            app.UnderstandingbeginswithawarenessandyourenotaloneonthisjourneyLabel.Text = {'"Understanding begins with awareness '; '— and you''re not alone on this journey." '};
            app.DepressionAssessmentTab = uitab(app.TabGroup);
            app.DepressionAssessmentTab.Title = 'Depression Assessment';
            app.CalculateButton = uibutton(app.DepressionAssessmentTab, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.DepressionAssessmentPanel = uipanel(app.DepressionAssessmentTab);
            app.DepressionAssessmentPanel.Title = 'Depression Assessment';
            app.FrustrationSliderLabel = uilabel(app.DepressionAssessmentPanel);
            app.FrustrationSliderLabel.Text = 'Frustration';
            app.FrustrationSlider = uislider(app.DepressionAssessmentPanel);
            app.FrustrationSlider.Limits = [0 1];
            app.FatigueSliderLabel = uilabel(app.DepressionAssessmentPanel);
            app.FatigueSliderLabel.Text = 'Fatigue';
            app.FatigueSlider = uislider(app.DepressionAssessmentPanel);
            app.FatigueSlider.Limits = [0 1];
            app.SuicidalthoughtSliderLabel = uilabel(app.DepressionAssessmentPanel);
            app.SuicidalthoughtSliderLabel.Text = 'Suicidal thought';
            app.SuicidalthoughtSlider = uislider(app.DepressionAssessmentPanel);
            app.SuicidalthoughtSlider.Limits = [0 1];
            app.Lamp_3 = uilamp(app.DepressionAssessmentPanel);
            app.DepressionLevelEditFieldLabel = uilabel(app.DepressionAssessmentPanel);
            app.DepressionLevelEditFieldLabel.Text = 'Depression Level';
            app.DepressionLevelEditField = uieditfield(app.DepressionAssessmentPanel, 'text');
            app.DepressionScaleGaugeLabel = uilabel(app.DepressionAssessmentPanel);
            app.DepressionScaleGaugeLabel.Text = 'Depression Scale';
            app.DepressionScaleGauge = uigauge(app.DepressionAssessmentPanel, 'linear');
            app.DepressionScaleGauge.Limits = [0 1];
            app.Image2_3 = uiimage(app.DepressionAssessmentTab);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'matlab1.jpg');
            app.BreatheeLabel_3 = uilabel(app.DepressionAssessmentTab);
            app.BreatheeLabel_3.Text = 'Breathee';
            app.YourJourneyAlmostBeginLabel = uilabel(app.DepressionAssessmentTab);
            app.YourJourneyAlmostBeginLabel.Text = 'Your Journey Almost Begin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = depression

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end